classdef IntegralesSustitucionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultadoTextArea           matlab.ui.control.TextArea
        TextAreaLabel               matlab.ui.control.Label
        CalcularIntegralButton      matlab.ui.control.Button
        IngreselafuncinfxEditField  matlab.ui.control.EditField
        IngreselafuncinaintegrarfxEditFieldLabel  matlab.ui.control.Label
        IngreselafuncinfxEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function resultado = calcularIntegralSustitucion(~, f_usuario)
            % Define la variable simbólica
            syms x;
            % Convertir la cadena de entrada a una expresión simbólica
            funcion = str2sym(f_usuario);
            
            % Calcula la integral indefinida utilizando el método de sustitución
            resultado = int(funcion, x);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularIntegralButton
        function CalcularIntegralButtonPushed(app, event)
             funcion = app.IngreselafuncinfxEditField.Value;
            try
                resultado = calcularIntegralSustitucion(app, funcion);
                app.ResultadoTextArea.Value = ['La integral indefinida es: ', char(resultado), ' + C'];
            catch ME
                app.ResultadoTextArea.Value = sprintf('Error: %s', ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IngreselafuncinfxEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinfxEditFieldLabel.Text = 'Ingrese la función a integrar f(x):';
            app.IngreselafuncinaintegrarfxEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinaintegrarfxEditFieldLabel.Text = 'Ingrese la función a integrar f(x):';
            app.IngreselafuncinfxEditField = uieditfield(app.UIFigure, 'text');
            app.CalcularIntegralButton = uibutton(app.UIFigure, 'push');
            app.CalcularIntegralButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularIntegralButtonPushed, true);
            app.CalcularIntegralButton.Text = 'Calcular Integral';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntegralesSustitucionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end